classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        verboseCheckBox           matlab.ui.control.CheckBox
        input4EditField           matlab.ui.control.NumericEditField
        input4EditFieldLabel      matlab.ui.control.Label
        input3EditField           matlab.ui.control.NumericEditField
        input3EditFieldLabel      matlab.ui.control.Label
        input2EditField           matlab.ui.control.NumericEditField
        input2EditFieldLabel      matlab.ui.control.Label
        input1EditField           matlab.ui.control.NumericEditField
        input1EditFieldLabel      matlab.ui.control.Label
        tastescoreEditField       matlab.ui.control.NumericEditField
        tastescoreEditFieldLabel  matlab.ui.control.Label
        EnterButton               matlab.ui.control.Button
        RiceTasteFuzzyInferenceSystemLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
            input1 = app.input1EditField.Value;
            input2 = app.input2EditField.Value;
            input3 = app.input3EditField.Value;
            input4 = app.input4EditField.Value;       
            inputData = [input1 input2 input3 input4];
            fis = readfis('riceTasteFIS.fis');
            prediction = evalfis(fis,inputData);
            app.tastescoreEditField.Value=prediction;
            
            value = app.verboseCheckBox.Value;
            switch value
                case 1
                    input1 = app.input1EditField.Value;
                    input2 = app.input2EditField.Value;
                    input3 = app.input3EditField.Value;
                    input4 = app.input4EditField.Value;       
                    inputData = [input1 input2 input3 input4];
                    fis = readfis('riceTasteFIS.fis');
                    [output,fuzzifiedIn,ruleOut,aggregatedOut,ruleFiring] = evalfis(fis,inputData);
                    outputRange = linspace(fis.output.range(1),fis.output.range(2),length(aggregatedOut))'; 
                    figure('Name',"output");
                    plot(outputRange,aggregatedOut,[output output],[0 1])
                    xlabel('Score')
                    ylabel('Output Membership')
                    legend('Aggregated output fuzzy set','Defuzzified output')
                case 0
                    %do nothing
            end


        end

        % Value changed function: verboseCheckBox
        function verboseCheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RiceTasteFuzzyInferenceSystemLabel = uilabel(app.UIFigure);
            app.RiceTasteFuzzyInferenceSystemLabel.Text = 'Rice Taste Fuzzy Inference System';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.tastescoreEditFieldLabel = uilabel(app.UIFigure);
            app.tastescoreEditFieldLabel.Text = 'taste score';
            app.tastescoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.input1EditFieldLabel = uilabel(app.UIFigure);
            app.input1EditFieldLabel.Text = 'input 1';
            app.input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.input2EditFieldLabel = uilabel(app.UIFigure);
            app.input2EditFieldLabel.Text = 'input 2';
            app.input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.input3EditFieldLabel = uilabel(app.UIFigure);
            app.input3EditFieldLabel.Text = 'input 3';
            app.input3EditField = uieditfield(app.UIFigure, 'numeric');
            app.input4EditFieldLabel = uilabel(app.UIFigure);
            app.input4EditFieldLabel.Text = 'input 4';
            app.input4EditField = uieditfield(app.UIFigure, 'numeric');
            app.verboseCheckBox = uicheckbox(app.UIFigure);
            app.verboseCheckBox.ValueChangedFcn = createCallbackFcn(app, @verboseCheckBoxValueChanged, true);
            app.verboseCheckBox.Text = 'verbose';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end